classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Joint1SliderLabel  matlab.ui.control.Label
        Joint1Slider       matlab.ui.control.Slider
        Joint2SliderLabel  matlab.ui.control.Label
        Joint2Slider       matlab.ui.control.Slider
        Joint3SliderLabel  matlab.ui.control.Label
        Joint3Slider       matlab.ui.control.Slider
        Joint4SliderLabel  matlab.ui.control.Label
        Joint4Slider       matlab.ui.control.Slider
        Joint5SliderLabel  matlab.ui.control.Label
        Joint5Slider       matlab.ui.control.Slider
        Joint6SliderLabel  matlab.ui.control.Label
        Joint6Slider       matlab.ui.control.Slider
        GraficarButton     matlab.ui.control.Button
        q1                 matlab.ui.control.Label
        q2                 matlab.ui.control.Label
        q3                 matlab.ui.control.Label
        q4                 matlab.ui.control.Label
        q5                 matlab.ui.control.Label
        q6                 matlab.ui.control.Label
        CinemticaDirectadeunrobotIRB120Label  matlab.ui.control.Label
        Grupo720211SLabel  matlab.ui.control.Label
        q1Label            matlab.ui.control.Label
        q2Label            matlab.ui.control.Label
        q3Label            matlab.ui.control.Label
        q4Label            matlab.ui.control.Label
        q5Label            matlab.ui.control.Label
        q6Label            matlab.ui.control.Label
        degLabel           matlab.ui.control.Label
        degLabel_2         matlab.ui.control.Label
        degLabel_3         matlab.ui.control.Label
        degLabel_4         matlab.ui.control.Label
        degLabel_5         matlab.ui.control.Label
        degLabel_6         matlab.ui.control.Label
    end

    
    properties (Access = private)
        Conf1 % confiuracion articulacion 1
        Conf2 % confiuracion articulacion 2
        Conf3 % confiuracion articulacion 3
        Conf4 % confiuracion articulacion 4
        Conf5 % confiuracion articulacion 5
        Conf6 % confiuracion articulacion 6
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue1 = event.Value;
            app.Conf1=changingValue1*pi/180;
            app.q1.Text=num2str(app.Conf1*180/pi);
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue2 = event.Value;
            app.Conf2=changingValue2*pi/180;
            app.q2.Text=num2str(app.Conf2*180/pi);
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue3 = event.Value;
            app.Conf3=changingValue3*pi/180;
            app.q3.Text=num2str(app.Conf3*180/pi);
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue4 = event.Value;
            app.Conf4=changingValue4*pi/180;
            app.q4.Text=num2str(app.Conf4*180/pi);
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            changingValue5 = event.Value;
            app.Conf5=changingValue5*pi/180;
            app.q5.Text=num2str(app.Conf5*180/pi);
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            changingValue6 = event.Value;
            app.Conf6=changingValue6*pi/180;
            app.q6.Text=num2str(app.Conf6*180/pi);
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
L(1) = Link('revolute'   ,'alpha',      0,  'a',  0,  'd',   0.290 , 'offset',   0, 'qlim',  [-11*pi/12 11*pi/12],   'modified');
L(2) = Link('revolute'   ,'alpha',   -pi/2,  'a',  0,  'd',   0    , 'offset',   -pi/2, 'qlim',  [-11*pi/18 11*pi/18],   'modified');
L(3) = Link('revolute'  ,'alpha',      0,  'a', 0.27,  'd',   0    , 'offset',   0, 'qlim',  [-11*pi/18 7*pi/18],   'modified');
L(4) = Link('revolute'   ,'alpha',  -pi/2,  'a',0.07,  'd',  0.302 , 'offset',   0, 'qlim',  [-8*pi/9 8*pi/9],   'modified');
L(5) = Link('revolute'   ,'alpha',   pi/2,  'a',  0,  'd',    0    , 'offset',   0, 'qlim',  [-2*pi/3 2*pi/3],   'modified');
L(6) = Link('revolute'   ,'alpha',  -pi/2,  'a',  0,  'd',    0    , 'offset',   0, 'qlim',  [-20*pi/9 20*pi/9],   'modified');


IRB120= SerialLink(L,'name', 'IRB 120');
IRB120.tool=transl(0,0,0.072)*troty(pi/2)*trotz(pi);

 q_gui=[app.Conf1 app.Conf2 app.Conf3 app.Conf4 app.Conf5 app.Conf6];
 ws=[-1 1 -1 1 -1 1];
 IRB120.teach(q_gui);
 IRB120.plot(q_gui,'workspace', ws, 'tilesize', 0.5,'view',[-25 150])
  
        end

        % Callback function
        function TextAreaValueChanged(app, event)
       app.EditField=app.Conf1;            
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function EditFieldValueChanged2(app, event)
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-165 165];
            app.Joint1Slider.MajorTicks = [-165 -135 -105 -75 -45 -15 0 15 45 75 105 135 165];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-110 110];
            app.Joint2Slider.MajorTicks = [-110 -80 -60 -40 -20 0 20 40 60 80 110];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint2Slider.Value = 22.44;
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-110 70];
            app.Joint3Slider.MajorTicks = [-110 -80 -60 -40 -20 0 20 40 60 70];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint3Slider.Value = 28.08;
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-160 160];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-120 120];
            app.Joint5Slider.MajorTicks = [-120 -100 -80 -60 -40 -20 0 20 40 60 80 100 120];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint5Slider.Value = -5.53;
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-400 400];
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.q1 = uilabel(app.UIFigure);
            app.q2 = uilabel(app.UIFigure);
            app.q3 = uilabel(app.UIFigure);
            app.q4 = uilabel(app.UIFigure);
            app.q5 = uilabel(app.UIFigure);
            app.q6 = uilabel(app.UIFigure);
            app.CinemticaDirectadeunrobotIRB120Label = uilabel(app.UIFigure);
            app.CinemticaDirectadeunrobotIRB120Label.Text = 'Cinemática Directa de un robot IRB 120';
            app.Grupo720211SLabel = uilabel(app.UIFigure);
            app.Grupo720211SLabel.Text = 'Grupo 7     2021-1S';
            app.q1Label = uilabel(app.UIFigure);
            app.q1Label.Text = 'q1=';
            app.q2Label = uilabel(app.UIFigure);
            app.q2Label.Text = 'q2=';
            app.q3Label = uilabel(app.UIFigure);
            app.q3Label.Text = 'q3=';
            app.q4Label = uilabel(app.UIFigure);
            app.q4Label.Text = 'q4=';
            app.q5Label = uilabel(app.UIFigure);
            app.q5Label.Text = 'q5=';
            app.q6Label = uilabel(app.UIFigure);
            app.q6Label.Text = 'q6=';
            app.degLabel = uilabel(app.UIFigure);
            app.degLabel.Text = 'deg';
            app.degLabel_2 = uilabel(app.UIFigure);
            app.degLabel_2.Text = 'deg';
            app.degLabel_3 = uilabel(app.UIFigure);
            app.degLabel_3.Text = 'deg';
            app.degLabel_4 = uilabel(app.UIFigure);
            app.degLabel_4.Text = 'deg';
            app.degLabel_5 = uilabel(app.UIFigure);
            app.degLabel_5.Text = 'deg';
            app.degLabel_6 = uilabel(app.UIFigure);
            app.degLabel_6.Text = 'deg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end